classdef gimbal_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TrajLabel                       matlab.ui.control.Label
        tauCheckBox                     matlab.ui.control.CheckBox
        q1desCheckBox                   matlab.ui.control.CheckBox
        q2desCheckBox                   matlab.ui.control.CheckBox
        dq1desCheckBox                  matlab.ui.control.CheckBox
        dq2desCheckBox                  matlab.ui.control.CheckBox
        eCheckBox                       matlab.ui.control.CheckBox
        deCheckBox                      matlab.ui.control.CheckBox
        dq1CheckBox                     matlab.ui.control.CheckBox
        dq2CheckBox                     matlab.ui.control.CheckBox
        q2CheckBox                      matlab.ui.control.CheckBox
        q1CheckBox                      matlab.ui.control.CheckBox
        PlotresultsButton               matlab.ui.control.Button
        SimulationsettingsPanel         matlab.ui.container.Panel
        OpenSystemButton                matlab.ui.control.Button
        StepsizeEditField               matlab.ui.control.NumericEditField
        StepsizeEditFieldLabel          matlab.ui.control.Label
        StoptimeEditField               matlab.ui.control.NumericEditField
        StoptimeEditFieldLabel          matlab.ui.control.Label
        SimulationControls              simulink.ui.control.SimulationControls
        TrajectorysettingsPanel         matlab.ui.container.Panel
        TypeDropDown                    matlab.ui.control.DropDown
        TypeDropDownLabel               matlab.ui.control.Label
        PeriodEditField                 matlab.ui.control.NumericEditField
        PeriodEditFieldLabel            matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        DynamixelconnectionPanel        matlab.ui.container.Panel
        ConnectButton                   matlab.ui.control.Button
        COMPortDropDown                 matlab.ui.control.DropDown
        COMPortDropDownLabel            matlab.ui.control.Label
        HardwareConnectionSwitch        matlab.ui.control.Switch
        HardwareConnectionSwitchLabel   matlab.ui.control.Label
        UncertaintyvaluesPanel          matlab.ui.container.Panel
        LengthEditFieldLabel            matlab.ui.control.Label
        MassUCEditField                 matlab.ui.control.NumericEditField
        InertiaUCEditField              matlab.ui.control.NumericEditField
        InertiaEditFieldLabel           matlab.ui.control.Label
        MassEditFieldLabel              matlab.ui.control.Label
        LengthUCEditField               matlab.ui.control.NumericEditField
        CloseUCButton                   matlab.ui.control.Button
        EditUCvaluesCheckBox            matlab.ui.control.CheckBox
        UncertaintySwitch               matlab.ui.control.Switch
        UncertaintySwitchLabel          matlab.ui.control.Label
        MovingTargetSwitch              matlab.ui.control.Switch
        MovingTargetSwitchLabel         matlab.ui.control.Label
        ControllerselectionButtonGroup  matlab.ui.container.ButtonGroup
        OtherType                       matlab.ui.control.DropDown
        OtherButton                     matlab.ui.control.RadioButton
        LearningButton                  matlab.ui.control.RadioButton
        AdaptiveTypeDropDown            matlab.ui.control.DropDown
        RobustTypeDropDown              matlab.ui.control.DropDown
        SlidingmodeButton               matlab.ui.control.RadioButton
        AdaptiveButton                  matlab.ui.control.RadioButton
        ExactmodelknowledgeButton       matlab.ui.control.RadioButton
        UIAxes                          matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        dynamixel % Description
    end
    
    methods (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            gimbal = gimbal_parameters;
            assignin('base', 'gimbal', gimbal);
        end

        % Selection changed function: ControllerselectionButtonGroup
        function ControllerselectionButtonGroupSelectionChanged(app, event)
            % General function to handle n number of buttons
            buttonGroup = app.ControllerselectionButtonGroup;
            
            idx = buttonGroup.Buttons == buttonGroup.SelectedObject;

            nButtons = length(buttonGroup.Buttons);
            
            selectedController = 0:nButtons-1;
            selectedController = selectedController(idx);
            
            gimbal = evalin('base', 'gimbal');
            gimbal.sim.activeController = selectedController;
            assignin('base', 'gimbal', gimbal);
        end

        % Value changed function: MovingTargetSwitch
        function MovingTargetSwitchValueChanged(app, event)
            value = app.MovingTargetSwitch.Value;
            
            if value == "On"
                setVal = 1;
                app.TrajectorysettingsPanel.Visible = 'off';
                app.TrajLabel.Visible = 'on';
            else
                setVal = 0;
                app.TrajectorysettingsPanel.Visible = 'on';
                app.TrajLabel.Visible = 'off';
            end

            gimbal = evalin('base', 'gimbal');
            gimbal.sim.movingTarget = setVal;
            assignin('base', 'gimbal', gimbal);
        end

        % Value changed function: UncertaintySwitch
        function UncertaintySwitchValueChanged(app, event)
            value = app.UncertaintySwitch.Value;
            app.EditUCvaluesCheckBox.Enable = value;
            
            gimbal = evalin('base', 'gimbal');

            if value == "Off"
                gimbal.uc.mass = 0;
                gimbal.uc.inertia = 0;
                gimbal.uc.length = 0;
            else
                gimbal.uc.mass = app.MassUCEditField.Value;
                gimbal.uc.inertia = app.InertiaUCEditField.Value;
                gimbal.uc.length = app.LengthUCEditField.Value;
            end
            assignin('base', 'gimbal', gimbal);
        end

        % Value changed function: EditUCvaluesCheckBox
        function EditUCvaluesCheckBoxValueChanged(app, event)
            st = ["off","on"];
            app.UncertaintyvaluesPanel.Visible = st(app.EditUCvaluesCheckBox.Value+1);
        end

        % Value changed function: MassUCEditField
        function MassUCEditFieldValueChanged(app, event)
            value = app.MassUCEditField.Value;
            
            gimbal = evalin('base', 'gimbal');
            gimbal.uc.mass = value;
            assignin('base', 'gimbal', gimbal);
        end

        % Value changed function: InertiaUCEditField
        function InertiaUCEditFieldValueChanged(app, event)
            value = app.InertiaUCEditField.Value;
            
            gimbal = evalin('base', 'gimbal');
            gimbal.uc.inertia = value;
            assignin('base', 'gimbal', gimbal);
        end

        % Value changed function: LengthUCEditField
        function LengthUCEditFieldValueChanged(app, event)
            value = app.LengthUCEditField.Value;
            
            gimbal = evalin('base', 'gimbal');
            gimbal.uc.length = value;
            assignin('base', 'gimbal', gimbal);
        end

        % Button pushed function: CloseUCButton
        function CloseUCButtonPushed(app, event)
            app.UncertaintyvaluesPanel.Visible = 'off';
            app.EditUCvaluesCheckBox.Value = 0;
        end

        % Drop down opening function: COMPortDropDown
        function COMPortDropDownOpening(app, event)
            app.COMPortDropDown.Items = serialportlist;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.Lamp.Color = [0.93 0.69 0.13];
            comport = convertStringsToChars(app.COMPortDropDown.Value);
            app.dynamixel = dynamixel_connect(comport);
            app.Lamp.Color = [0 1 0];

            assignin('base', 'dynamixel', app.dynamixel);
        end

        % Value changed function: HardwareConnectionSwitch
        function HardwareConnectionSwitchValueChanged(app, event)
            value = app.HardwareConnectionSwitch.Value;
            app.Lamp.Visible = value;
            app.DynamixelconnectionPanel.Visible = value;

            if value == "On"
                setVal = 1;
            else
                setVal = 0;
            end

            gimbal = evalin('base', 'gimbal');
            gimbal.sim.hardwareConnection = setVal;
            assignin('base', 'gimbal', gimbal);
        end

        % Value changed function: RobustTypeDropDown
        function RobustTypeDropDownValueChanged(app, event)
            value = app.RobustTypeDropDown.Value;
            value = str2double(value);
            gimbal = evalin('base', 'gimbal');
            gimbal.sim.robustType = value;
            assignin('base', 'gimbal', gimbal);
        end

        % Value changed function: StoptimeEditField
        function StoptimeEditFieldValueChanged(app, event)
            value = app.StoptimeEditField.Value;
            gimbal = evalin('base', 'gimbal');
            gimbal.sim.stopTime = value;
            assignin('base', 'gimbal', gimbal);
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = str2double(app.TypeDropDown.Value);
            gimbal = evalin('base', 'gimbal');
            gimbal.sim.traj.trajSelect = value;
            assignin('base', 'gimbal', gimbal);
        end

        % Value changed function: PeriodEditField
        function PeriodEditFieldValueChanged(app, event)
            value = app.PeriodEditField.Value;
            gimbal = evalin('base', 'gimbal');
            gimbal.sim.traj.T = value;
            assignin('base', 'gimbal', gimbal);
        end

        % Value changed function: StepsizeEditField
        function StepsizeEditFieldValueChanged(app, event)
            value = app.StepsizeEditField.Value;
            gimbal = evalin('base', 'gimbal');
            gimbal.sim.Ts = value;
            assignin('base', 'gimbal', gimbal);
        end

        % Button pushed function: PlotresultsButton
        function PlotresultsButtonPushed(app, event)
            
        end

        % Button pushed function: OpenSystemButton
        function OpenSystemButtonPushed(app, event)
            open_system("gimbal_simulink");
        end

        % Value changed function: AdaptiveTypeDropDown
        function AdaptiveTypeDropDownValueChanged(app, event)
            value = app.AdaptiveTypeDropDown.Value;
            value = str2double(value);
            gimbal = evalin('base', 'gimbal');
            gimbal.sim.adaptiveType = value;
            assignin('base', 'gimbal', gimbal);
        end

        % Value changed function: OtherType
        function OtherTypeValueChanged(app, event)
            value = app.OtherType.Value;
            value = str2double(value);
            gimbal = evalin('base', 'gimbal');
            gimbal.sim.otherType = value;
            assignin('base', 'gimbal', gimbal);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ControllerselectionButtonGroup = uibuttongroup(app.UIFigure);
            app.ControllerselectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ControllerselectionButtonGroupSelectionChanged, true);
            app.ControllerselectionButtonGroup.Title = 'Controller selection';
            app.ExactmodelknowledgeButton = uiradiobutton(app.ControllerselectionButtonGroup);
            app.ExactmodelknowledgeButton.Text = 'Exact model knowledge';
            app.ExactmodelknowledgeButton.Value = true;
            app.AdaptiveButton = uiradiobutton(app.ControllerselectionButtonGroup);
            app.AdaptiveButton.Text = 'Adaptive';
            app.SlidingmodeButton = uiradiobutton(app.ControllerselectionButtonGroup);
            app.SlidingmodeButton.Text = 'Sliding mode';
            app.RobustTypeDropDown = uidropdown(app.ControllerselectionButtonGroup);
            app.RobustTypeDropDown.Items = {'High gain', 'High frequency', 'Sliding mode'};
            app.RobustTypeDropDown.ItemsData = {'0', '1', '2'};
            app.RobustTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @RobustTypeDropDownValueChanged, true);
            app.RobustTypeDropDown.Value = '0';
            app.AdaptiveTypeDropDown = uidropdown(app.ControllerselectionButtonGroup);
            app.AdaptiveTypeDropDown.Items = {'Standard', 'Desired model comp.'};
            app.AdaptiveTypeDropDown.ItemsData = {'0', '1'};
            app.AdaptiveTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AdaptiveTypeDropDownValueChanged, true);
            app.AdaptiveTypeDropDown.Value = '0';
            app.LearningButton = uiradiobutton(app.ControllerselectionButtonGroup);
            app.LearningButton.Text = 'Learning';
            app.OtherButton = uiradiobutton(app.ControllerselectionButtonGroup);
            app.OtherButton.Text = 'Other';
            app.OtherType = uidropdown(app.ControllerselectionButtonGroup);
            app.OtherType.Items = {'RISE', 'Supertwisting'};
            app.OtherType.ItemsData = {'0', '1'};
            app.OtherType.ValueChangedFcn = createCallbackFcn(app, @OtherTypeValueChanged, true);
            app.OtherType.Value = '0';
            app.MovingTargetSwitchLabel = uilabel(app.UIFigure);
            app.MovingTargetSwitchLabel.Text = 'Moving Target';
            app.MovingTargetSwitch = uiswitch(app.UIFigure, 'slider');
            app.MovingTargetSwitch.ValueChangedFcn = createCallbackFcn(app, @MovingTargetSwitchValueChanged, true);
            app.MovingTargetSwitch.Tooltip = {'Turns on or off moving target tracking (task space or joint space)'};
            app.UncertaintySwitchLabel = uilabel(app.UIFigure);
            app.UncertaintySwitchLabel.Text = 'Uncertainty';
            app.UncertaintySwitch = uiswitch(app.UIFigure, 'slider');
            app.UncertaintySwitch.ValueChangedFcn = createCallbackFcn(app, @UncertaintySwitchValueChanged, true);
            app.EditUCvaluesCheckBox = uicheckbox(app.UIFigure);
            app.EditUCvaluesCheckBox.ValueChangedFcn = createCallbackFcn(app, @EditUCvaluesCheckBoxValueChanged, true);
            app.EditUCvaluesCheckBox.Enable = 'off';
            app.EditUCvaluesCheckBox.Text = 'Edit values';
            app.UncertaintyvaluesPanel = uipanel(app.UIFigure);
            app.UncertaintyvaluesPanel.Title = 'Uncertainty values';
            app.UncertaintyvaluesPanel.Visible = 'off';
            app.CloseUCButton = uibutton(app.UncertaintyvaluesPanel, 'push');
            app.CloseUCButton.ButtonPushedFcn = createCallbackFcn(app, @CloseUCButtonPushed, true);
            app.CloseUCButton.Text = 'Close';
            app.LengthUCEditField = uieditfield(app.UncertaintyvaluesPanel, 'numeric');
            app.LengthUCEditField.Limits = [0 Inf];
            app.LengthUCEditField.ValueChangedFcn = createCallbackFcn(app, @LengthUCEditFieldValueChanged, true);
            app.LengthUCEditField.Value = 0.0075;
            app.MassEditFieldLabel = uilabel(app.UncertaintyvaluesPanel);
            app.MassEditFieldLabel.Text = 'Mass ';
            app.InertiaEditFieldLabel = uilabel(app.UncertaintyvaluesPanel);
            app.InertiaEditFieldLabel.Text = 'Inertia';
            app.InertiaUCEditField = uieditfield(app.UncertaintyvaluesPanel, 'numeric');
            app.InertiaUCEditField.Limits = [0 Inf];
            app.InertiaUCEditField.ValueChangedFcn = createCallbackFcn(app, @InertiaUCEditFieldValueChanged, true);
            app.InertiaUCEditField.Value = 5e-06;
            app.MassUCEditField = uieditfield(app.UncertaintyvaluesPanel, 'numeric');
            app.MassUCEditField.Limits = [0 Inf];
            app.MassUCEditField.ValueChangedFcn = createCallbackFcn(app, @MassUCEditFieldValueChanged, true);
            app.MassUCEditField.Value = 0.005;
            app.LengthEditFieldLabel = uilabel(app.UncertaintyvaluesPanel);
            app.LengthEditFieldLabel.Text = 'Length';
            app.HardwareConnectionSwitchLabel = uilabel(app.UIFigure);
            app.HardwareConnectionSwitchLabel.Text = 'Hardware Connection';
            app.HardwareConnectionSwitch = uiswitch(app.UIFigure, 'slider');
            app.HardwareConnectionSwitch.ValueChangedFcn = createCallbackFcn(app, @HardwareConnectionSwitchValueChanged, true);
            app.DynamixelconnectionPanel = uipanel(app.UIFigure);
            app.DynamixelconnectionPanel.Title = 'Dynamixel connection';
            app.DynamixelconnectionPanel.Visible = 'off';
            app.COMPortDropDownLabel = uilabel(app.DynamixelconnectionPanel);
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown = uidropdown(app.DynamixelconnectionPanel);
            app.COMPortDropDown.Items = {'COM 1', 'COM 2'};
            app.COMPortDropDown.DropDownOpeningFcn = createCallbackFcn(app, @COMPortDropDownOpening, true);
            app.COMPortDropDown.Value = 'COM 1';
            app.ConnectButton = uibutton(app.DynamixelconnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Visible = 'off';
            app.TrajectorysettingsPanel = uipanel(app.UIFigure);
            app.TrajectorysettingsPanel.Title = 'Trajectory settings';
            app.PeriodEditFieldLabel = uilabel(app.TrajectorysettingsPanel);
            app.PeriodEditFieldLabel.Text = 'Period';
            app.PeriodEditField = uieditfield(app.TrajectorysettingsPanel, 'numeric');
            app.PeriodEditField.Limits = [0.001 Inf];
            app.PeriodEditField.ValueChangedFcn = createCallbackFcn(app, @PeriodEditFieldValueChanged, true);
            app.PeriodEditField.Value = 2;
            app.TypeDropDownLabel = uilabel(app.TrajectorysettingsPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.TrajectorysettingsPanel);
            app.TypeDropDown.Items = {'Sinusoidal', '5th order'};
            app.TypeDropDown.ItemsData = {'0', '1'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = '0';
            app.SimulationsettingsPanel = uipanel(app.UIFigure);
            app.SimulationsettingsPanel.Title = 'Simulation settings';
            app.SimulationControls = uisimcontrols(app.SimulationsettingsPanel);
            app.SimulationControls.Simulation = app.Simulation;
            app.StoptimeEditFieldLabel = uilabel(app.SimulationsettingsPanel);
            app.StoptimeEditFieldLabel.Text = 'Stop time';
            app.StoptimeEditField = uieditfield(app.SimulationsettingsPanel, 'numeric');
            app.StoptimeEditField.ValueChangedFcn = createCallbackFcn(app, @StoptimeEditFieldValueChanged, true);
            app.StoptimeEditField.Value = 10;
            app.StepsizeEditFieldLabel = uilabel(app.SimulationsettingsPanel);
            app.StepsizeEditFieldLabel.Text = 'Step size';
            app.StepsizeEditField = uieditfield(app.SimulationsettingsPanel, 'numeric');
            app.StepsizeEditField.ValueChangedFcn = createCallbackFcn(app, @StepsizeEditFieldValueChanged, true);
            app.StepsizeEditField.Value = 0.005;
            app.OpenSystemButton = uibutton(app.SimulationsettingsPanel, 'push');
            app.OpenSystemButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSystemButtonPushed, true);
            app.OpenSystemButton.Text = 'Open System';
            app.PlotresultsButton = uibutton(app.UIFigure, 'push');
            app.PlotresultsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotresultsButtonPushed, true);
            app.PlotresultsButton.Text = 'Plot results';
            app.q1CheckBox = uicheckbox(app.UIFigure);
            app.q1CheckBox.Text = 'q1';
            app.q2CheckBox = uicheckbox(app.UIFigure);
            app.q2CheckBox.Text = 'q2';
            app.dq2CheckBox = uicheckbox(app.UIFigure);
            app.dq2CheckBox.Text = 'dq2';
            app.dq1CheckBox = uicheckbox(app.UIFigure);
            app.dq1CheckBox.Text = 'dq1';
            app.deCheckBox = uicheckbox(app.UIFigure);
            app.deCheckBox.Text = 'de';
            app.eCheckBox = uicheckbox(app.UIFigure);
            app.eCheckBox.Text = 'e';
            app.dq2desCheckBox = uicheckbox(app.UIFigure);
            app.dq2desCheckBox.Text = 'dq2des';
            app.dq1desCheckBox = uicheckbox(app.UIFigure);
            app.dq1desCheckBox.Text = 'dq1des';
            app.q2desCheckBox = uicheckbox(app.UIFigure);
            app.q2desCheckBox.Text = 'q2des';
            app.q1desCheckBox = uicheckbox(app.UIFigure);
            app.q1desCheckBox.Text = 'q1des';
            app.tauCheckBox = uicheckbox(app.UIFigure);
            app.tauCheckBox.Text = 'tau';
            app.TrajLabel = uilabel(app.UIFigure);
            app.TrajLabel.Visible = 'off';
            app.TrajLabel.Text = 'Trajectory is defined in Simulink block!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gimbal_app

            % Associate the Simulink Model
            app.Simulation = simulation('gimbal_simulink');

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end